classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        Label                matlab.ui.control.Label
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        RightPanel           matlab.ui.container.Panel
        Button               matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        EditField2           matlab.ui.control.NumericEditField
        EditField            matlab.ui.control.NumericEditField
        Sigma_hpSlider       matlab.ui.control.Slider
        Sigma_hpSliderLabel  matlab.ui.control.Label
        Sigma_lpSlider       matlab.ui.control.Slider
        Sigma_lpSliderLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        sigma_lp;
        sigma_hp;
        Imagea;
        Imageb;% Description
    end
    methods (Access = private)
        function preprocess(app)
            Imga = imread('aiyinsitan.jpg'); 			% 读取jpg图像文件
            Imgb= imread('menglu.jpg'); 				% 读取jpg图像文件
            app.Imagea = imresize(Imga, [180*2, 132*2], 'bilinear'); % 截取相同图像大小
            app.Imageb = imresize(Imgb, [180*2, 132*2], 'bilinear'); % 截取相同图像大小
            app.Imagea = rgb2gray(app.Imagea);  					%转换为灰度
            app.Imageb = rgb2gray(app.Imageb); 	%转换为灰度
            %imshow(app.Imagea,'Parent',app.UIAxes);
        end

        
        function [mix]=mix_image(app)
            %提取频谱并平移
            s1=fftshift(fft2(app.Imagea));
            s2=fftshift(fft2(app.Imageb));
            

            %用于高通的高斯函数
            mu1=132.5;mu2=180.5;
            sigma11=app.sigma_hp;sigma21=app.sigma_hp;rho=0;
            [x,y] = meshgrid(1:264,1:360);
            gauss1=exp(-1/(2*(1-rho^2))*((x-mu1).^2/sigma11^2-2*rho.*(x-mu1).*(y-mu2)/(sigma11*sigma21)+(y-mu2).^2/sigma21^2))*1/(2*pi*sigma11*sigma21*sqrt(1-rho^2));
            gauss1=(gauss1-min(min(gauss1)))/(max(max(gauss1))-min(min(gauss1)));
            igauss=1-gauss1;
            %用于低通的高斯函数
            sigma1=app.sigma_lp;sigma2=app.sigma_hp;rho=0;
            [x,y] = meshgrid(1:264,1:360);
            %gauss=exp(-1/(2*(1-rho^2))*((x-mu1).^2/sigma1^2-2*rho.*(x-mu1).*(y-mu2)/(sigma1*sigma2)+(y-mu2).^2/sigma2^2))*1/(2*pi*sigma1*sigma2*sqrt(1-rho^2));
            gauss=exp(-1/(2*(1-rho^2))*((x-mu1).^2/sigma1^2-2*rho.*(x-mu1).*(y-mu2)/(sigma1*sigma2)+(y-mu2).^2/sigma2^2))*1/(2*pi*sigma1*sigma2*sqrt(1-rho^2));
            gauss=(gauss-min(min(gauss)))/(max(max(gauss))-min(min(gauss)));

            %滤波
            s1f=s1.*igauss;
            Image1_filter=uint8(real(ifft2(ifftshift(s1f))));
            s2f=s2.*gauss;
            Image2_filter=uint8(real(ifft2(ifftshift(s2f))));
            
            %五官对齐
            J=imcrop(Image1_filter,[13 1 248 359]);
            J=imresize(J,[350, 132*2], 'bilinear');
            J=[J;zeros(10,264)];
            K=[zeros(341,8),imcrop(Image2_filter,[1 20 260 340]),zeros(341,8)];
            K=imresize(K,[180*2, 132*2], 'bilinear');
            K=imtranslate(K,[-8, 12],'FillValues',0);
            K=imrotate(K,2.5,'crop');
            %图像混合及显示
            mix=1.8.*imfuse(J,K,"blend","Scaling","joint");
            imshow(mix,'Parent',app.UIAxes);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sigma_lp=5;
            app.sigma_hp=5;
            preprocess(app);
            mix_image(app);
        end

        % Value changed function: Sigma_lpSlider
        function Sigma_lpSliderValueChanged(app, event)
            value = app.Sigma_lpSlider.Value;
            app.sigma_lp=value;
            app.EditField.Value=value;
            mix_image(app);
        end

        % Value changed function: Sigma_hpSlider
        function Sigma_hpSliderValueChanged(app, event)
            value = app.Sigma_hpSlider.Value;
            app.sigma_hp=value;
            app.EditField2.Value=value;
            mix_image(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.sigma_lp=value;
            app.Sigma_lpSlider.Value=value;
            mix_image(app);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            app.sigma_lp=value;
            app.Sigma_hpSlider.Value=value;
            mix_image(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            mix=mix_image(app);
            imwrite(mix,['混合后图像hp',num2str(app.sigma_hp),'lp',num2str(app.sigma_lp),'.png'])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {708, 708};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {413, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'aiyinsitan.jpg';
            app.Image3 = uiimage(app.LeftPanel);
            app.Image3.ImageSource = 'menglu.jpg';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '题目1. 爱因斯坦一秒变梦露之谜';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '处理后图像      ')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Sigma_lpSliderLabel = uilabel(app.RightPanel);
            app.Sigma_lpSliderLabel.Text = 'Sigma_lp';
            app.Sigma_lpSlider = uislider(app.RightPanel);
            app.Sigma_lpSlider.Limits = [5 30];
            app.Sigma_lpSlider.ValueChangedFcn = createCallbackFcn(app, @Sigma_lpSliderValueChanged, true);
            app.Sigma_lpSlider.Value = 5;
            app.Sigma_hpSliderLabel = uilabel(app.RightPanel);
            app.Sigma_hpSliderLabel.Text = 'Sigma_hp';
            app.Sigma_hpSlider = uislider(app.RightPanel);
            app.Sigma_hpSlider.Limits = [5 30];
            app.Sigma_hpSlider.ValueChangedFcn = createCallbackFcn(app, @Sigma_hpSliderValueChanged, true);
            app.Sigma_hpSlider.Value = 5;
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField.Limits = [5 30];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 5;
            app.EditField2 = uieditfield(app.RightPanel, 'numeric');
            app.EditField2.Limits = [5 30];
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.Value = 5;
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Sigma_hp控制爱因斯坦图像的高通滤波；Sigma_lp控制梦露图像的低通滤波。'; '滑动滑块或输入以调节参数。'};
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '说明';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'save.png');
            app.Button.Text = '保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end